classdef visualize_Angles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OptionsMenu               matlab.ui.container.Menu
        LoadDataMenu              matlab.ui.container.Menu
        SaveDataMenu              matlab.ui.container.Menu
        SaveAsMenu                matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        lEditField                matlab.ui.control.NumericEditField
        lEditFieldLabel           matlab.ui.control.Label
        l                         matlab.ui.control.Slider
        lSliderLabel              matlab.ui.control.Label
        SumEigStatus              matlab.ui.control.Label
        useCustomR                matlab.ui.control.CheckBox
        TabGroup                  matlab.ui.container.TabGroup
        AnglesTab                 matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        zEditField                matlab.ui.control.NumericEditField
        zEditFieldLabel           matlab.ui.control.Label
        z                         matlab.ui.control.Slider
        zSliderLabel              matlab.ui.control.Label
        transformed               matlab.ui.control.UIAxes
        orginal                   matlab.ui.control.UIAxes
        SystemsTab                matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        RcustomEigLabel           matlab.ui.control.Label
        custReigTable             matlab.ui.control.Table
        EigenvaluesLabel_5        matlab.ui.control.Label
        BeqEigTable               matlab.ui.control.Table
        EigenvaluesLabel_4        matlab.ui.control.Label
        AeqEigTable               matlab.ui.control.Table
        CustomRLabel              matlab.ui.control.Label
        custRTable                matlab.ui.control.Table
        BRRBLabel                 matlab.ui.control.Label
        BeqTable                  matlab.ui.control.Table
        ARRALabel                 matlab.ui.control.Label
        AeqTable                  matlab.ui.control.Table
        EigenvaluesLabel_3        matlab.ui.control.Label
        EigenvaluesLabel_2        matlab.ui.control.Label
        EigenvaluesLabel          matlab.ui.control.Label
        calcReigTable             matlab.ui.control.Table
        BeigTable                 matlab.ui.control.Table
        AeigTable                 matlab.ui.control.Table
        CanEdit                   matlab.ui.control.StateButton
        EnablematrixeditingLabel  matlab.ui.control.Label
        RLabel                    matlab.ui.control.Label
        BLabel                    matlab.ui.control.Label
        ALabel                    matlab.ui.control.Label
        calcRTable                matlab.ui.control.Table
        BTable                    matlab.ui.control.Table
        ATable                    matlab.ui.control.Table
        TrajectoriesTab           matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        UpdateButton              matlab.ui.control.Button
        TranTraj                  matlab.ui.control.UIAxes
        OrgTraj                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        saveAs
        SwSys
        custR
        calcR
        Rmat
        Eqs
        isTblUpToDate
        lambda
        res
        ang
        levels
        x
        y
        X
        Y
    end

    methods (Access = private)
        % Skipta upp i org og transformed til að gera hraðar.
        function update_plot(app, both)
            try
                for r=1:app.res
                    for s=1:app.res
                        if app.SwSys.Dim==2
                            xi=[app.x(r);app.y(s)];
                        else
                            xi=[app.x(r);app.y(s);app.z.Value];
                        end
                        if both
                            a=app.SwSys.A.Mat*xi;
                            b=app.SwSys.B.Mat*xi;
                            app.ang(r,s,1)=acosd(max(min(dot(a,b)/(norm(a)*norm(b)),1),-1));
                        end

                        a=app.Rmat^(1/2)*app.SwSys.A.Mat*app.Rmat^(-1/2)*xi;
                        b=app.Rmat^(1/2)*app.SwSys.B.Mat*app.Rmat^(-1/2)*xi;
                        app.ang(r,s,2)=acosd(max(min(dot(a,b)/(norm(a)*norm(b)),1),-1));
                    end
                end
                if both
                    [~,h]=contourf(app.orginal,app.X, app.Y, app.ang(:,:,1),app.levels);
                    set(h,'LineColor','none');
                end
                [~,h]=contourf(app.transformed,app.X, app.Y, app.ang(:,:,2),app.levels);
                set(h,'LineColor','none');

                if both
                    angels=reshape(app.ang(:,:,1),1,[]);
                    app.orginal.XLabel.String=sprintf('Max = %.2f, Min = %.2f,\n Mean = %.2f, Std = %.2f',...
                        max(angels),min(angels),mean(angels), std(angels));
                end
                angels=reshape(app.ang(:,:,2),1,[]);
                app.transformed.XLabel.String=sprintf('Max = %.2f, Min = %.2f,\n Mean = %.2f, Std = %.2f',...
                    max(angels),min(angels),mean(angels), std(angels));
            catch e
                uialert(app.UIFigure,'Could not update plot. R is probably the issue.','Error Message','Interpreter','html');
                rethrow(e)
            end
        end

        function plot_traj(app, both)
            if app.SwSys.Dim==2
                if both
                    tmp=generate_trajectorie(app, app.SwSys.A.Mat);
                    plot(app.OrgTraj,tmp(1,:),tmp(2,:));
                    hold(app.OrgTraj,'on');
                    tmp=generate_trajectorie(app, app.SwSys.B.Mat);
                    plot(app.OrgTraj,tmp(1,:),tmp(2,:));
                    hold(app.OrgTraj,'off');
                    legend(app.OrgTraj,'A','B');
                    grid(app.OrgTraj,'on');
                end
                tmp=generate_trajectorie(app, app.Rmat^(1/2)*app.SwSys.A.Mat*app.Rmat^(-1/2));
                plot(app.TranTraj,tmp(1,:),tmp(2,:));
                hold(app.TranTraj,'on');
                tmp=generate_trajectorie(app, app.Rmat^(1/2)*app.SwSys.B.Mat*app.Rmat^(-1/2));
                plot(app.TranTraj,tmp(1,:),tmp(2,:));
                hold(app.TranTraj,'off');
                legend(app.TranTraj,'A','B');
                grid(app.TranTraj,'on');
            else
                if both
                    tmp=generate_trajectorie(app, app.SwSys.A.Mat);
                    plot3(app.OrgTraj,tmp(1,:),tmp(2,:),tmp(3,:));
                    hold(app.OrgTraj,'on');
                    tmp=generate_trajectorie(app, app.SwSys.B.Mat);
                    plot3(app.OrgTraj,tmp(1,:),tmp(2,:),tmp(3,:));
                    hold(app.OrgTraj,'off');
                    legend(app.OrgTraj,'A','B');
                    grid(app.OrgTraj,'on');
                end
                tmp=generate_trajectorie(app, app.Rmat^(1/2)*app.SwSys.A.Mat*app.Rmat^(-1/2));
                plot3(app.TranTraj,tmp(1,:),tmp(2,:),tmp(3,:));
                hold(app.TranTraj,'on');
                tmp=generate_trajectorie(app, app.Rmat^(1/2)*app.SwSys.B.Mat*app.Rmat^(-1/2));
                plot3(app.TranTraj,tmp(1,:),tmp(2,:),tmp(3,:));
                hold(app.TranTraj,'off');
                legend(app.TranTraj,'A','B');
                grid(app.TranTraj,'on');
            end
        end

        function traj=generate_trajectorie(app, matrix)
            f=@(t,x) matrix*x;
            init=ones(app.SwSys.Dim,1);
            [t,x]=ode45(f,[0 1000],init);
            traj=x';
        end


        function update_tbl(app)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Updating tables');
            if ~app.isTblUpToDate.A
                app.ATable.Data=app.SwSys.A.Mat;
                app.AeigTable.Data = app.SwSys.A.Eig;
                app.isTblUpToDate.A=true;
            end
            d.Value = .1667;
            if ~app.isTblUpToDate.B
                app.BTable.Data=app.SwSys.B.Mat;
                app.BeigTable.Data = app.SwSys.B.Eig;
                app.isTblUpToDate.B=true;
            end
            d.Value = d.Value+.1667;
            if ~app.isTblUpToDate.eqA
                app.AeqTable.Data=app.Eqs.A.Mat;
                app.AeqEigTable.Data=app.Eqs.A.Eig;
                app.isTblUpToDate.eqA=true;
            end
            d.Value = d.Value+.1667;
            if ~app.isTblUpToDate.eqB
                app.BeqTable.Data=app.Eqs.B.Mat;
                app.BeqEigTable.Data=app.Eqs.B.Eig;
                app.isTblUpToDate.eqB=true;
            end
            d.Value = d.Value+.1667;
            if ~app.isTblUpToDate.calcR
                app.calcRTable.Data = app.calcR.Mat;
                app.calcReigTable.Data = app.calcR.Eig;
                app.isTblUpToDate.calcR=true;
            end
            d.Value = d.Value+.1667;
            if ~app.isTblUpToDate.custR
                app.custRTable.Data = app.custR.Mat;
                app.custReigTable.Data = app.custR.Eig;
                app.isTblUpToDate.custR=true;
            end
            close(d);
        end

        function onSystemChange(app)
            app.calcR.Set(app.lambda*app.SwSys.A.LyapunovMatrix...
                +(1-app.lambda)*app.SwSys.B.LyapunovMatrix);
            app.Eqs.A.Set(app.SwSys.A.Mat'*app.Rmat...
                +app.Rmat*app.SwSys.A.Mat);
            app.Eqs.B.Set(app.SwSys.B.Mat'*app.Rmat...
                +app.Rmat*app.SwSys.B.Mat);
            app.isTblUpToDate.calcR = false;
            app.isTblUpToDate.eqA=false;
            app.isTblUpToDate.eqB=false;
            if app.SwSys.isSumHurwitz
                app.SumEigStatus.Text = 'The eigenvalues of A+B are strictly negative';
            else
                app.SumEigStatus.Text = 'The eigenvalues of A+B are not strictly negative';
            end
        end

        function switchRs(app, type)
            calcEqs=false;
            if strcmpi(type,'switch')
                app.useCustomR.Value= (~app.useCustomR.Value);
                app.Rmat=app.calcR.Mat*(app.useCustomR.Value==0)+...
                    app.custR.Mat*(app.useCustomR.Value==1);
                calcEqs=true;
            elseif strcmpi(type,'custR')
                if and(app.useCustomR.Value,app.isTblUpToDate.custR)
                    calcEqs=false;
                else
                    app.useCustomR.Value=true;
                    app.Rmat=app.custR.Mat;
                    calcEqs=true;
                end
            elseif strcmpi(type,'calcR')
                if and(~app.useCustomR.Value,app.isTblUpToDate.calcR)
                    calcEqs=false;
                else
                    app.useCustomR.Value=false;
                    app.Rmat=app.calcR.Mat;
                    calcEqs=true;
                end
            end

            if calcEqs
                app.Eqs.A.Set(app.SwSys.A.Mat'*app.Rmat...
                    +app.Rmat*app.SwSys.A.Mat);
                app.Eqs.B.Set(app.SwSys.B.Mat'*app.Rmat...
                    +app.Rmat*app.SwSys.B.Mat);
                app.isTblUpToDate.eqA=false;
                app.isTblUpToDate.eqB=false;
            end

            changeCustRvisibility(app, app.useCustomR.Value)
        end

        function onDimChange(app)
            app.calcR.Set(app.lambda*app.SwSys.A.LyapunovMatrix...
                +(1-app.lambda)*app.SwSys.B.LyapunovMatrix);
            if app.custR.Dim>app.SwSys.Dim
                app.custR.Set(app.custR.Mat(1:2,1:2));
                app.isTblUpToDate.custR = false;
                app.z.Enable='off';
                app.zEditField.Enable='off';
            elseif app.custR.Dim<app.SwSys.Dim
                app.custR.Set([app.custR.Mat(1:2,1:2),zeros(2,1);zeros(1,2) ,1]);
                app.isTblUpToDate.custR = false;
                app.z.Enable='on';
                app.zEditField.Enable='on';
            end
            app.Rmat=app.calcR.Mat*(app.useCustomR.Value==0)+...
                app.custR.Mat*(app.useCustomR.Value==1);

        end

        function newLambda(app)
            app.lEditField.Value = app.lambda;
            app.l.Value = app.lambda ;
            app.calcR.Set(app.lambda*app.SwSys.A.LyapunovMatrix...
                +(1-app.lambda)*app.SwSys.B.LyapunovMatrix);
            app.isTblUpToDate.calcR=false;

            switchRs(app, 'calcR')
            update_tbl(app);
        end

        function changeCustRvisibility(app, value)
            app.custRTable.Visible=value;
            app.custReigTable.Visible=value;
            app.CustomRLabel.Visible=value;
            app.RcustomEigLabel.Visible=value;
            app.l.Enable=~value;
            app.lEditField.Enable=~value;
        end

        function readData(app)
            % Ef það klikkar eitthvað þarf að spola til baka.
            % Ef
            newA = app.ATable.Data;
            newB = app.BTable.Data;
            newR = app.custRTable.Data;

            [ABok, ABdimChanged, AMatChanged, BMatChanged] = app.SwSys.Set('A',newA,'B',newB);
            [Rok, ~, RMatChanged] = app.custR.Set(newR);
            if or(~ABok, ~Rok)
                uialert(app.UIFigure,'Something went wrong when reading the new inputs.','Error Message','Interpreter','html');
                app.isTblUpToDate.A = false;
                app.isTblUpToDate.B = false;
                app.isTblUPToDate.custR = false;
                update_tbl(app);
                return
            end

            both = false;
            if RMatChanged
                app.isTblUpToDate.custR=false;
                switchRs(app, 'custR');
            end
            if ABdimChanged
                onDimChange(app);
            end
            if or(AMatChanged,BMatChanged)
                app.isTblUpToDate.A=~AMatChanged;
                app.isTblUpToDate.B=~BMatChanged;
                onSystemChange(app);                
                both = true;
            end

            update_tbl(app);
            update_plot(app, both);
            plot_traj(app, both);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Initializing application');
            pause(.5)
            % Constants
            app.levels=linspace(0,180,20);
            app.res=20;
            app.ang=zeros(app.res,app.res,2);
            app.x=linspace(-1,1,app.res);
            app.y=app.x;
            [app.X, app.Y]=meshgrid(app.x,app.y);

            d.Value = .33;
            pause(0.5)
            % Initialize private
            app.isTblUpToDate.A=false;
            app.isTblUpToDate.B=false;
            app.isTblUpToDate.eqA=false;
            app.isTblUpToDate.eqB=false;
            app.isTblUpToDate.calcR=false;
            app.isTblUpToDate.custR=false;
            app.SwSys = SwitchedSystem(LinearSystem([0 1; -0.1 -2]),...
                LinearSystem([0 1; -5 -2]));
            app.custR = SquareMatrix(eye(2));
            app.lambda=0.5;
            app.calcR = SquareMatrix(app.lambda*app.SwSys.A.LyapunovMatrix...
                +(1-app.lambda)*app.SwSys.B.LyapunovMatrix);
            app.Eqs.A=SquareMatrix(app.SwSys.A.Mat'*app.calcR.Mat...
                +app.calcR.Mat*app.SwSys.A.Mat);
            app.Eqs.B=SquareMatrix(app.SwSys.B.Mat'*app.calcR.Mat...
                +app.calcR.Mat*app.SwSys.B.Mat);
            d.Value = .66;
            pause(0.5)
            % Initialize public
            app.lSliderLabel.Text={[char(955)]};
            app.lEditFieldLabel.Text={[char(955)]};
            app.RLabel.Text = {['R=' char(955) 'P_A+(1-' char(955) ')P_B']};
            app.SumEigStatus.Text = "The eigenvalues of A+B are strictly negative";
            app.z.Enable='off';
            app.zEditField.Enable='off';
            app.l.Value = 0.5;
            app.Rmat=app.calcR.Mat;
            app.saveAs='AppData';

            % Sync
            update_tbl(app);
            d.Value = 1;
            pause(0.5)
            update_plot(app, true);
            plot_traj(app,true);
            close(d)

        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            [newA, newB, newR]=load_from_workspace(app);
            [OK, DimChanged, AMatChanged, BMatChanged] = app.SwSys.Set('A',newA,'B',newB);
            if ~OK
                uialert(app.UIFigure,'Something went wrong when loading from file.','Error Message','Interpreter','html');
                return
            end
            both = false;
            if size(newR,1)~=1
                app.custR.Set(newR);
                app.isTblUpToDate.custR=false;
                switchRs(app, 'custR');
            end
            if DimChanged
                onDimChange(app);
            end
            if or(AMatChanged,BMatChanged)
                app.isTblUpToDate.A=~AMatChanged;
                app.isTblUpToDate.B=~BMatChanged;
                onSystemChange(app);
            end

            if or(AMatChanged, AMatChanged)
                % plot_traj(app);
                both = true;
            end
            update_tbl(app);
            update_plot(app, both);
            plot_traj(app,both);


            function [A, B, R]=load_from_workspace(app)
                A=[]; B=[]; R=[];
                fileName = uigetfile('*.mat');
                if fileName==0
                    return
                end
                tbl=whos('-file',fileName);
                if isempty(tbl)
                    uialert(app.UIFigure,'The file is empty.','Error Message','Interpreter','html');
                    return
                end
                classes={'double','single','half','int8','int16','int32','int64','uint8','uint16','uint32','uint64'};
                list={'None'};
                for i=1:length(tbl)
                    if ismember(tbl(i).class,classes)
                        if and(length(tbl(i).size)>1,length(tbl(i).size)<3)
                            if and(tbl(i).size(1)==tbl(i).size(2), ismember(tbl(i).size(1),[2 3]))
                                list{end+1}=[tbl(i).name sprintf(', size:[%i x %i]',tbl(i).size(1),tbl(i).size(2))];
                            end
                        end
                    end
                end

                d=dialog(Position=[300 300 250 150], ...
                    Name='Load variable');

                txt = uicontrol(Parent=d,...
                    Style='text',...
                    String='Choose which variables to load',...
                    Position=[0 120 250 25]);

                Atxt = uicontrol('Parent',d,...
                    Style='text',...
                    Position=[47.5 90 30 25],...
                    String='A = ');
                AdropDown = uicontrol(Parent=d,...
                    Style="popup",...
                    String=list, ...
                    Position = [77.5 92.5 120 25]);

                Btxt = uicontrol('Parent',d,...
                    Style='text',...
                    Position=[47.5 60 30 25],...
                    String='B = ');
                BdropDown = uicontrol(Parent=d,...
                    Style="popup",...
                    String=list,...
                    Position = [77.5 62.5 120 25]);

                Rtxt = uicontrol('Parent',d,...
                    Style='text',...
                    Position=[47.5 30 30 25],...
                    String='R = ');
                RdropDown = uicontrol(Parent=d,...
                    Style="popup",...
                    String=list, ...
                    Position = [77.5 32.5 120 25]);

                btnCancel = uicontrol(Parent=d,...
                    Position=[170 3.75 70 25],...
                    String='Cancel',...
                    Callback='delete(gcf)');
                btnOK = uicontrol(Parent=d,...
                    Position=[95 3.75 70 25],...
                    String='Load',...
                    Callback={@load_callback, AdropDown, BdropDown, RdropDown});


                uiwait(d);
                function load_callback(button,~,AdropDown, BdropDown, RdropDown)
                    if AdropDown.Value~=1
                        A=cell2mat(struct2cell(load(fileName,list{AdropDown.Value}(1:end-14))));
                    else
                        A=1;
                    end
                    if BdropDown.Value~=1
                        B=cell2mat(struct2cell(load(fileName,list{BdropDown.Value}(1:end-14))));
                    else
                        B=1;
                    end
                    if RdropDown.Value~=1
                        R=cell2mat(struct2cell(load(fileName,list{RdropDown.Value}(1:end-14))));
                    else
                        R=1;
                    end
                    delete(button.Parent)
                end
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            plot_traj(app, true);
        end

        % Value changed function: useCustomR
        function useCustomRValueChanged(app, event)
            app.useCustomR.Value = ~app.useCustomR.Value;
            switchRs(app, 'switch')
            update_tbl(app);
            update_plot(app, false);
            plot_traj(app, false);
        end

        % Value changing function: l
        function lValueChanging(app, event)
            value= event.Value;
            app.Rmat = value*app.SwSys.A.LyapunovMatrix...
                +(1-value)*app.SwSys.B.LyapunovMatrix;
            update_plot(app, false);
            plot_traj(app, false);
        end

        % Value changed function: l, lEditField
        function lValueChanged(app, event)
            app.lambda = event.Value;
            newLambda(app)
            update_plot(app, false);
            plot_traj(app, false);
        end

        % Value changing function: z
        function zValueChanging(app, event)
            app.z.Value = event.Value;
            update_plot(app, true);
        end

        % Value changed function: z
        function zValueChanged(app, event)
            app.zEditField.Value = app.z.Value;
            update_plot(app, true);
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            app.z.Value = app.zEditField.Value;
            update_plot(app, true);
        end

        % Value changed function: CanEdit
        function CanEditValueChanged(app, event)
            value = app.CanEdit.Value;
            if value
                app.CanEdit.Text="Enabled";
                app.custRTable.ColumnEditable = true;
                app.ATable.ColumnEditable = true;
                app.BTable.ColumnEditable = true;
            else
                app.CanEdit.Text="Locked";
                app.custRTable.ColumnEditable = false;
                app.ATable.ColumnEditable = false;
                app.BTable.ColumnEditable = false;
                readData(app);
            end
        end

        % Menu selected function: SaveDataMenu
        function SaveDataMenuSelected(app, event)
            A = app.SwSys.A.Mat;
            B = app.SwSys.B.Mat;
            custR = app.custR.Mat;
            calcR = app.calcR.Mat;
            lambda = app.lambda;
            save(append(app.saveAs, '.mat'), 'A', 'B', 'custR', 'calcR', 'lambda');
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            prompt = {'Enter file name'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {app.saveAs};
            app.saveAs = cell2mat(inputdlg(prompt,dlgtitle,dims,definput));
            SaveDataMenuSelected(app,event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.LoadDataMenu = uimenu(app.OptionsMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Text = 'Load Data';
            app.SaveDataMenu = uimenu(app.OptionsMenu);
            app.SaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataMenuSelected, true);
            app.SaveDataMenu.Text = 'Save Data';
            app.SaveAsMenu = uimenu(app.OptionsMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AnglesTab = uitab(app.TabGroup);
            app.AnglesTab.Title = 'Angles';
            app.GridLayout2 = uigridlayout(app.AnglesTab);
            app.orginal = uiaxes(app.GridLayout2);
            title(app.orginal, 'Orginal')
            xlabel(app.orginal, 'x')
            ylabel(app.orginal, 'y')
            app.orginal.CLim = [0 180];
            colormap(app.orginal, 'pink')
            app.transformed = uiaxes(app.GridLayout2);
            title(app.transformed, 'Transformed')
            xlabel(app.transformed, 'x')
            ylabel(app.transformed, 'y')
            app.transformed.CLim = [0 180];
            colormap(app.transformed, 'pink')
            app.zSliderLabel = uilabel(app.GridLayout2);
            app.zSliderLabel.Text = 'z';
            app.z = uislider(app.GridLayout2);
            app.z.Limits = [-1 1];
            app.z.MajorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.z.ValueChangedFcn = createCallbackFcn(app, @zValueChanged, true);
            app.z.ValueChangingFcn = createCallbackFcn(app, @zValueChanging, true);
            app.zEditFieldLabel = uilabel(app.GridLayout2);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.GridLayout2, 'numeric');
            app.zEditField.Limits = [-1 1];
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.SystemsTab = uitab(app.TabGroup);
            app.SystemsTab.Title = 'Systems';
            app.GridLayout3 = uigridlayout(app.SystemsTab);
            app.ATable = uitable(app.GridLayout3);
            app.ATable.ColumnName = '';
            app.ATable.RowName = {};
            app.ATable.Tag = 'A_data';
            app.BTable = uitable(app.GridLayout3);
            app.BTable.ColumnName = '';
            app.BTable.RowName = {};
            app.BTable.Tag = 'A_data';
            app.calcRTable = uitable(app.GridLayout3);
            app.calcRTable.ColumnName = '';
            app.calcRTable.RowName = {};
            app.calcRTable.Tag = 'A_data';
            app.ALabel = uilabel(app.GridLayout3);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.GridLayout3);
            app.BLabel.Text = 'B';
            app.RLabel = uilabel(app.GridLayout3);
            app.RLabel.Text = 'R';
            app.EnablematrixeditingLabel = uilabel(app.GridLayout3);
            app.EnablematrixeditingLabel.Text = 'Enable matrix editing';
            app.CanEdit = uibutton(app.GridLayout3, 'state');
            app.CanEdit.ValueChangedFcn = createCallbackFcn(app, @CanEditValueChanged, true);
            app.CanEdit.Text = 'Locked';
            app.AeigTable = uitable(app.GridLayout3);
            app.AeigTable.ColumnName = '';
            app.AeigTable.RowName = {};
            app.BeigTable = uitable(app.GridLayout3);
            app.BeigTable.ColumnName = '';
            app.BeigTable.RowName = {};
            app.calcReigTable = uitable(app.GridLayout3);
            app.calcReigTable.ColumnName = '';
            app.calcReigTable.RowName = {};
            app.EigenvaluesLabel = uilabel(app.GridLayout3);
            app.EigenvaluesLabel.Text = 'Eigenvalues';
            app.EigenvaluesLabel_2 = uilabel(app.GridLayout3);
            app.EigenvaluesLabel_2.Text = 'Eigenvalues';
            app.EigenvaluesLabel_3 = uilabel(app.GridLayout3);
            app.EigenvaluesLabel_3.Text = 'Eigenvalues';
            app.AeqTable = uitable(app.GridLayout3);
            app.AeqTable.ColumnName = '';
            app.AeqTable.RowName = {};
            app.AeqTable.Tag = 'A_data';
            app.ARRALabel = uilabel(app.GridLayout3);
            app.ARRALabel.Text = 'A''R+RA';
            app.BeqTable = uitable(app.GridLayout3);
            app.BeqTable.ColumnName = '';
            app.BeqTable.RowName = {};
            app.BeqTable.Tag = 'A_data';
            app.BRRBLabel = uilabel(app.GridLayout3);
            app.BRRBLabel.Text = 'B''R+RB';
            app.custRTable = uitable(app.GridLayout3);
            app.custRTable.ColumnName = '';
            app.custRTable.RowName = {};
            app.custRTable.Visible = 'off';
            app.custRTable.Tag = 'A_data';
            app.CustomRLabel = uilabel(app.GridLayout3);
            app.CustomRLabel.Tag = 'custRLable';
            app.CustomRLabel.Visible = 'off';
            app.CustomRLabel.Text = 'Custom R';
            app.AeqEigTable = uitable(app.GridLayout3);
            app.AeqEigTable.ColumnName = '';
            app.AeqEigTable.RowName = {};
            app.EigenvaluesLabel_4 = uilabel(app.GridLayout3);
            app.EigenvaluesLabel_4.Text = 'Eigenvalues';
            app.BeqEigTable = uitable(app.GridLayout3);
            app.BeqEigTable.ColumnName = '';
            app.BeqEigTable.RowName = {};
            app.EigenvaluesLabel_5 = uilabel(app.GridLayout3);
            app.EigenvaluesLabel_5.Text = 'Eigenvalues';
            app.custReigTable = uitable(app.GridLayout3);
            app.custReigTable.ColumnName = '';
            app.custReigTable.RowName = {};
            app.custReigTable.Visible = 'off';
            app.RcustomEigLabel = uilabel(app.GridLayout3);
            app.RcustomEigLabel.Tag = 'custReigLable';
            app.RcustomEigLabel.Visible = 'off';
            app.RcustomEigLabel.Text = 'Eigenvalues';
            app.TrajectoriesTab = uitab(app.TabGroup);
            app.TrajectoriesTab.Title = 'Trajectories';
            app.GridLayout4 = uigridlayout(app.TrajectoriesTab);
            app.OrgTraj = uiaxes(app.GridLayout4);
            title(app.OrgTraj, 'Orginal')
            xlabel(app.OrgTraj, 'X')
            ylabel(app.OrgTraj, 'Y')
            zlabel(app.OrgTraj, 'Z')
            app.TranTraj = uiaxes(app.GridLayout4);
            title(app.TranTraj, 'Transformed')
            xlabel(app.TranTraj, 'X')
            ylabel(app.TranTraj, 'Y')
            zlabel(app.TranTraj, 'Z')
            app.UpdateButton = uibutton(app.GridLayout4, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.useCustomR = uicheckbox(app.GridLayout);
            app.useCustomR.ValueChangedFcn = createCallbackFcn(app, @useCustomRValueChanged, true);
            app.useCustomR.Text = 'Use custom R';
            app.SumEigStatus = uilabel(app.GridLayout);
            app.SumEigStatus.Text = 'The eigenvalues of A+B are (not) strictly negative';
            app.lSliderLabel = uilabel(app.GridLayout);
            app.lSliderLabel.Text = 'l';
            app.l = uislider(app.GridLayout);
            app.l.Limits = [0 1];
            app.l.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.l.ValueChangedFcn = createCallbackFcn(app, @lValueChanged, true);
            app.l.ValueChangingFcn = createCallbackFcn(app, @lValueChanging, true);
            app.l.Value = 0.5;
            app.lEditFieldLabel = uilabel(app.GridLayout);
            app.lEditFieldLabel.Text = 'l';
            app.lEditField = uieditfield(app.GridLayout, 'numeric');
            app.lEditField.Limits = [0 1];
            app.lEditField.ValueChangedFcn = createCallbackFcn(app, @lValueChanged, true);
            app.lEditField.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualize_Angles

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end